classdef signalsonson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FiltersKnob                  matlab.ui.control.DiscreteKnob
        FiltersKnobLabel             matlab.ui.control.Label
        Label2                       matlab.ui.control.Label
        RPMLabel                     matlab.ui.control.Label
        BPMLabel                     matlab.ui.control.Label
        LableLabel                   matlab.ui.control.Label
        OrderofFilterEditField       matlab.ui.control.NumericEditField
        OrderofFilterEditFieldLabel  matlab.ui.control.Label
        LowPassCutOffFrequencyHzEditField  matlab.ui.control.NumericEditField
        LowPassCutOffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        HighPassCutOffFrequencyHzEditField  matlab.ui.control.NumericEditField
        HighPassCutOffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


     properties (Access = private)
        a 
        f
        f1
        Y
        Y1
        P1
        P2
        P3
        P4
        filteredPlot
        rawPlot
        serialport
        Fs = 8000;
        fs = 5000;
        fc1 = 30;
        fc2 = 200;
        T = 1/5000;
        n = 1;
        m = 1;
        m1 = 1;
        plotWindow = 2000;
        IsRunning = false;
        Timer
        Data
        ffData
        fdata 
        coefficient_1
        coefficient_2
        fc3=30
        bpm
        coefficient_3
        coefficient_4
        orderOfFilter=4;
        end

    methods (Access = private)
        
        function timerCallback = func(app)
            if ~app.IsRunning
            stop(app.Timer);
        end
    end
 end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IsRunning=true;
            app.Timer=timer;
            app.Timer.Period=0.1;
            app.Timer.ExecutionMode='fixedRate';
            app.Timer.TimerFcn=@(~,~)app.timerCallback;
            start(app.Timer);

            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.IsRunning = true;
            app.a = serialport("COM4", 19200);
            configureTerminator(app.a, "CR/LF");
            flush(app.a);
            app.a.UserData = struct("Data", [], "Order", 1, "fData", [],"ffData",[]);
            app.IsRunning = true;
            
            while app.a.UserData.Order < 100000
                    app.Data = readline(app.a);
                    app.a.UserData.Data(end+1) = str2double(app.Data);
                    app.a.UserData.Order = app.a.UserData.Order + 1;
                if mod(app.a.UserData.Order, 100) == 0
                    configureCallback(app.a, "off");

                    plot(app.UIAxes, app.a.UserData.Data(max(1,end-app.plotWindow+1):end))
                      [app.coefficient_1, app.coefficient_2] = butter(4, app.fc3/(app.fs/2), 'high');
        app.a.UserData.ffData = filter(app.coefficient_1, app.coefficient_2, app.a.UserData.Data(1:end));
                    [app.coefficient_3, app.coefficient_4] = butter(app.orderOfFilter, [app.fc1/(app.fs/2), app.fc2/(app.fs/2)], 'bandpass');

                    app.a.UserData.fData = filter(app.coefficient_3, app.coefficient_4, app.a.UserData.Data);
                    
                    plot(app.UIAxes_2, app.a.UserData.fData(max(1, end-app.plotWindow+1):end) * 3);
                    drawnow;
                    
                    configureTerminator(app.a, "CR/LF");

                end
                 if mod(app.a.UserData.Order,500)==0
                     configureCallback(app.a, "off");

                     app.Y = fft(app.a.UserData.ffData(end-499+1:end));
                     app.P2 = abs(app.Y / 500); % Use 400 for normalization
                     app.f = app.fs * (0:(length(app.Y)/2))/length(app.Y); % Adjust frequency axis
                     app.P1 = app.P2(1:length(app.Y)/2+1);
                     app.P1(2:end-1) = 2 * app.P1(2:end-1);
                     plot(app.UIAxes_3,app.f,app.P1,'Color','r');
                     set(gca,'ylim',[0,10]);

                     app.Y1 = fft(app.a.UserData.fData(end-499+1:end));
                     app.P3 = abs(app.Y1 / 500); % Use 400 for normalization
                     app.f1 = app.fs * (0:(length(app.Y1)/2))/length(app.Y1); % Adjust frequency axis
                     app.P4 = app.P3(1:length(app.Y1)/2+1);
                     app.P4(2:end-1) = 2 * app.P4(2:end-1);
                     plot(app.UIAxes_4, app.f1,app.P4,'Color','r');
                     set(gca,'ylim',[0,15]);

                     grid on;
                     app.m=app.m+500;
                     configureTerminator(app.a,"CR/LF");
                 end
                 if mod(app.a.UserData.Order, 5000) == 0
                % Dinamik eşik belirleme
                signalSegment = app.a.UserData.Data(end-4999+1:end);
                
                % Sinyali normalize et
                normalizedSegment = (signalSegment - min(signalSegment)) / (max(signalSegment) - min(signalSegment));
                
                % Eşiği daha adaptif hale getir (ortalama + standart sapma)
                threshold = mean(normalizedSegment) + 0.6 * std(normalizedSegment);
            
                % Tepe noktalarını bul (Minimum mesafe ekleyerek gürültüyü azalt)
                [peaks, locs] = findpeaks(normalizedSegment, 'MinPeakHeight', threshold, 'MinPeakDistance', round(app.fs * 0.4));
            
                % BPM Hesaplama (Tepe noktaları arası süreye göre)
                if numel(locs) > 1
                    interPeakIntervals = diff(locs) / app.fs;  % Peak aralıkları saniyeye çevrilir
                    avgInterval = mean(interPeakIntervals);
                    app.bpm = 60 / avgInterval;  % BPM Hesaplama
                    disp(['Measured BPM: ', num2str(int16(app.bpm))]);
                    app.LableLabel.Text = num2str(int16(app.bpm));
                else
                    app.bpm = 0;  % Yetersiz tepe noktası varsa BPM 0
                    disp('No Pulse Detected');
                    app.LableLabel.Text = '0';
                end
            end

             end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.IsRunning=false;
            delete(app.a);
        end

        % Value changed function: LowPassCutOffFrequencyHzEditField
        function LowPassCutOffFrequencyHzEditFieldValueChanged(app, event)
            b=app.LowPassCutOffFrequencyHzEditField.Value;
            app.fc2=b;
        end

        % Value changed function: HighPassCutOffFrequencyHzEditField
        function HighPassCutOffFrequencyHzEditFieldValueChanged(app, event)
             c= app.HighPassCutOffFrequencyHzEditField.Value;
             app.fc1=c;
        end

        % Value changed function: OrderofFilterEditField
        function OrderofFilterEditFieldValueChanged(app, event)
            j = app.OrderofFilterEditField.Value;
            app.orderOfFilter=j;
        end

        % Value changed function: FiltersKnob
        function FiltersKnobValueChanged(app, event)
            value = app.FiltersKnob.Value;
            app.filterType = app.FilterTypesKnob.Value;
    
                % applyFilter metodunu çağır
                switch app.filterType
                case 'lowpass'
                    [app.coefficient_3, app.coefficient_4] = butter(app.orderOfFilter, app.fc2 / (app.fs / 2), 'low');
                case 'highpass'
                    [app.coefficient_3, app.coefficient_4] = butter(app.orderOfFilter, app.fc1 / (app.fs / 2), 'high');
                case 'bandpass'
                    [app.coefficient_3, app.coefficient_4] = butter(app.orderOfFilter, [app.fc1 / (app.fs / 2), app.fc2 / (app.fs / 2)], 'bandpass');
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Signal')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Filtered Signal')
            xlabel(app.UIAxes_2, 'Time(s)')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Raw Signal FFT')
            xlabel(app.UIAxes_3, 'Frequency(Hz)')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Filtered Signal FFT')
            xlabel(app.UIAxes_4, 'Frequency(Hz)')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HighPassCutOffFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.HighPassCutOffFrequencyHzEditFieldLabel.Text = 'High Pass Cut Off Frequency (Hz)';
            app.HighPassCutOffFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighPassCutOffFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassCutOffFrequencyHzEditFieldValueChanged, true);
            app.HighPassCutOffFrequencyHzEditField.Value = 30;
            app.LowPassCutOffFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.LowPassCutOffFrequencyHzEditFieldLabel.Text = 'Low Pass Cut Off Frequency (Hz)';
            app.LowPassCutOffFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowPassCutOffFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassCutOffFrequencyHzEditFieldValueChanged, true);
            app.LowPassCutOffFrequencyHzEditField.Value = 200;
            app.OrderofFilterEditFieldLabel = uilabel(app.UIFigure);
            app.OrderofFilterEditFieldLabel.Text = 'Order of Filter';
            app.OrderofFilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderofFilterEditField.ValueChangedFcn = createCallbackFcn(app, @OrderofFilterEditFieldValueChanged, true);
            app.OrderofFilterEditField.Value = 4;
            app.LableLabel = uilabel(app.UIFigure);
            app.LableLabel.Text = 'Lable';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.RPMLabel = uilabel(app.UIFigure);
            app.RPMLabel.Text = 'RPM';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.FiltersKnobLabel = uilabel(app.UIFigure);
            app.FiltersKnobLabel.Text = 'Filters';
            app.FiltersKnob = uiknob(app.UIFigure, 'discrete');
            app.FiltersKnob.Items = {'Low pass', 'High pass', 'Band pass'};
            app.FiltersKnob.ValueChangedFcn = createCallbackFcn(app, @FiltersKnobValueChanged, true);
            app.FiltersKnob.Value = 'Low pass';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signalsonson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end